classdef program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentnetworkPanel             matlab.ui.container.Panel
        ClearNN                         matlab.ui.control.Button
        noteLabel                       matlab.ui.control.Label
        DoyouhavealoadednetLamp         matlab.ui.control.Lamp
        DoyouhavealoadednetLampLabel    matlab.ui.control.Label
        CreatetheNeuralNetworkPanel     matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        RemoveoneButton                 matlab.ui.control.Button
        NumberEpochsEditField           matlab.ui.control.NumericEditField
        NumberEpochsEditFieldLabel      matlab.ui.control.Label
        NeuronsEditField                matlab.ui.control.NumericEditField
        NeuronsEditFieldLabel           matlab.ui.control.Label
        OutputFunctionDropDown          matlab.ui.control.DropDown
        OutputFunctionDropDownLabel     matlab.ui.control.Label
        ActivationFunctionDropDown      matlab.ui.control.DropDown
        ActivationFunctionDropDownLabel  matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        FilesPanel                      matlab.ui.container.Panel
        ExportButton                    matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        TestCheckBox                    matlab.ui.control.CheckBox
        TrainCheckBox                   matlab.ui.control.CheckBox
        StartCheckBox                   matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        ClassifyButton                  matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        logTextArea                     matlab.ui.control.EditField
        LogsEditFieldLabel              matlab.ui.control.Label
        transferFunctionTable           matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        TrainParams                     matlab.ui.container.Panel
        TrainButton                     matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        TrainFunctionDropDown           matlab.ui.control.DropDown
        TrainFunctionDropDownLabel      matlab.ui.control.Label
        DivisionFunctionsDropDown       matlab.ui.control.DropDown
        DivisionFunctionsDropDownLabel  matlab.ui.control.Label
        ValidationRatioEditField        matlab.ui.control.NumericEditField
        ValidationRatioEditFieldLabel   matlab.ui.control.Label
        TestRatioEditField              matlab.ui.control.NumericEditField
        TestRatioEditFieldLabel         matlab.ui.control.Label
        TrainRatioEditField             matlab.ui.control.NumericEditField
        TrainRatioEditFieldLabel        matlab.ui.control.Label
        RatioSlider                     matlab.ui.control.RangeSlider
    end

    
    % Variáveis internas de controlo e dados
    properties (Access = private)
        % Lista de classes geométricas para classificação
        classes = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};

        % Lista de funções de ativação disponíveis
        activationFcnNames = categorical({ ...
                'compet', 'hardlim', 'hardlims', ...
                'logsig', 'netinv', 'poslin', ...
                'purelin', 'radbas', 'radbasn', ...
                'satlin', 'satlins', 'softmax', ...
                'tansig', 'tribas'});
        activationFcn = [];        % Funções de ativação usadas na rede criada
        topology = [10];           % Topologia atual (nº de neurónios por camada escondida)
        isValid = false;           % Flag de validação de entrada
        epochs = 100;              % Número de épocas (treino)
        trainFcn = 'trainlm';      % Função de treino
        outputFcn = 'purelin';     % Função de ativação da camada de saída
        divideFcn = 'dividerand';  % Função de divisão de dados
       
        % Percentagens de divisão de dados
        trainRatio = 70;
        testRatio = 15;
        valRatio = 15;
        
        % Estrutura com rede treinada e histórico
        bestNet = struct('net', {}, 'tr', {});
        binaryImage;    % Imagem atual carregada e vetorizada
    end
    
    methods (Access = private)
        
        function updateFields(app)
            % Atualiza todos os elementos da interface com os valores atuais

            % Table with topology and activation functions
            % Atualiza tabela com funções de ativação e topologia
            app.transferFunctionTable.Data = table(app.activationFcn', app.topology');

            % Divide parameters and function
            % Atualiza parâmetros de divisão e função de divisão
            app.DivisionFunctionsDropDown.Value = app.divideFcn;
            app.TestRatioEditField.Value = app.testRatio;
            app.TrainRatioEditField.Value = app.trainRatio;
            app.ValidationRatioEditField.Value = app.valRatio;

            % Ration slider
            % Atualiza a posição do slider com base nos rácios
            app.RatioSlider.Value = [app.valRatio (100 - app.testRatio)];

            % Train function and output function
            % Atualiza os dropdowns com a função de treino e função de saída atuais
            app.TrainFunctionDropDown.Value = app.trainFcn;
            app.OutputFunctionDropDown.Value = app.outputFcn;

            % Num Epochs
            % Atualiza o número de épocas no campo correspondente
            app.NumberEpochsEditField.Value = app.epochs;
        end
        
        function [logMessage, trained] = trainNN(app, binaries, target)

            % Garante acesso às funções auxiliares (caso necessárias)
            addpath('helper\');
            
            % Inicializa a flag para indicar se o treino foi realizado
            trained = false;

            % Se já existir uma rede carregada, treina essa rede novamente
            if ~isempty(app.bestNet)
                net = app.bestNet.net;    % Recupera a rede existente da propriedade bestNet

                % Train the neural network
                % Treina a rede com os dados fornecidos
                [net, tr] = train(net, binaries, target);

                % Simulate the network
                % Simula a rede treinada para obter as saídas previstas
                out = sim(net, binaries);

                % visualizar a rede
                % view(net);
                % disp(tr);
                    
                % Calcule the global precision
                % % % Avaliação da precisão global % % % 
                valid = true;
                r = 0;    % Contador de classificações corretas
                for i = 1:size(out, 2)
                    try
                        [~, c] = max(out(:, i));      % Classe predita
                        [~, e] = max(target(:, i));   % Classe esperada
                        if isempty(c) || isempty(e)
                            valid = false;
                            break;
                        end
                        if c == e
                            r = r + 1;
                        end
                    catch
                        valid = false;
                        break;
                    end
                end
                
                % Apresenta a precisão no terminal/log
                if valid
                    trainAccuracy = r / size(out, 2) * 100;
                    fprintf('Precisão Global: %.2f%%\n', trainAccuracy);
                else
                    fprintf('Erro: A saída da rede não foi válida para avaliar a precisão.\n');
                    trainAccuracy = NaN;
                end

                % Atualiza a propriedade interna com a rede treinada e respetivo histórico
                app.bestNet = struct('net', net, 'tr', tr);

                % Mensagem de log para apresentar no painel da app
                logMessage = sprintf(' Precisão Global: %.2f%%\n', trainAccuracy);
                trained = true;
            else
                % % % % Caso em que ainda não existe rede criada (primeira vez) % % % % 

                % Cria uma nova rede com a topologia e função de treino escolhidas
                net = feedforwardnet(app.topology, app.trainFcn);

                % Aplica as funções de ativação às camadas escondidas
                for k = 1:numel(app.topology)
                    net.layers{k}.transferFcn = string(app.activationFcn(k));
                end

                % Define a função de ativação da camada de saída
                net.layers{end}.transferFcn = app.outputFcn;

                % Define o método e os rácios de divisão dos dados
                net.divideFcn = app.divideFcn;
                net.divideParam.trainratio = app.trainRatio;
                net.divideParam.valRatio = app.valRatio;
                net.divideParam.testRatio = app.testRatio;

                % Define o número de épocas de treino
                net.trainParam.epochs = app.epochs;
    
                % Train the neural network
                % % % %  Treino e simulação da nova rede % % % % 
                [net, tr] = train(net, binaries, target);
                % Simulate the network
                out = sim(net, binaries);


                % visualizar a rede
                % view(net);
                % disp(tr);
                    
                % Calcule the global precision
                % % % %  Avaliação da precisão global % % % %  
                valid = true;
                r = 0;
                for i = 1:size(out, 2)
                    try
                        [~, c] = max(out(:, i));
                        [~, e] = max(target(:, i));
                        if isempty(c) || isempty(e)
                            valid = false;
                            break;
                        end
                        if c == e
                            r = r + 1;
                        end
                    catch
                        valid = false;
                        break;
                    end
                end
                
                if valid
                    trainAccuracy = r / size(out, 2) * 100;
                    fprintf('Precisão Global: %.2f%%\n', trainAccuracy);
                else
                    fprintf('Erro: A saída da rede não foi válida para avaliar a precisão.\n');
                    trainAccuracy = NaN;
                end

                % Guarda a rede treinada e histórico
                app.bestNet = struct('net', net, 'tr', tr);

                % Atualiza os elementos visuais para indicar que há uma rede carregada
                app.DoyouhavealoadednetLamp.Color = 'g';     % Torna o "lamp" verde
                app.noteLabel.Visible = true;                % Mostra o aviso de que a rede está carregada
                app.ClearNN.Visible = true;                  % Mostra o botão de limpar rede

                % Atualiza o log com o resultado do treino
                logMessage = sprintf(' Precisão Global: %.2f%%\n', trainAccuracy);
                trained = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % % % % EXECUTADO QUANDO A APP É INICIADA % % % %  
            app.activationFcn = [app.activationFcnNames(13)];   % Valor inicial: 'tansig'
            updateFields(app);     % Atualiza todos os campos da GUI com os valores por defeito
            app.DoyouhavealoadednetLamp.Color = 'r';   % Define a cor do 'lamp' como vermelho (sem rede carregada)
            app.noteLabel.Visible = false;             % Esconde a nota de rede carregada
            app.ClearNN.Visible = false;               % Esconde botão de apagar rede
        end

        % Value changing function: RatioSlider
        function RatioSliderValueChanging(app, event)
            % % % % ATUALIZA RATIOS QUANDO O SLIDER É MOVIDO % % % % 
            changingValue = event.Value;
            app.testRatio = 100 - changingValue(2);         % Calcula test ratio
            app.trainRatio = changingValue(2) - changingValue(1);  % Calcula train ratio
            app.valRatio = changingValue(1);                % Val ratio é o valor mais à esquerda
    
            % Caso o slider esteja a 0-0, considera divisão 'dividetrain'
            if changingValue(2) == 0 && changingValue(1) == 0
                app.divideFcn = 'dividetrain';
            end
            updateFields(app);  % Atualiza campos na interface
        end

        % Value changed function: DivisionFunctionsDropDown
        function DivisionFunctionsDropDownValueChanged(app, event)
            % % % % QUANDO O UTILIZADOR MUDA A FUNÇÃO DE DIVISÃO % % % % 
            value = app.DivisionFunctionsDropDown.Value;

            % Se a divisão não for 'dividetrain', assume 100% para treino
            if strcmp(value, 'dividetrain') == 0
                app.TrainRatioEditField.Value = 100;
                app.TestRatioEditField.Value = 0;
                app.ValidationRatioEditField.Value = 0;
                app.RatioSlider.Value = [0 0];
            end
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
             % % % % QUANDO CLICA NO BOTÃO "TRAIN" % % % % 
            if app.TestCheckBox.Value == 0 && app.TrainCheckBox.Value == 0 && app.StartCheckBox.Value == 0
                app.logTextArea.Value = "Please choose one file to train the NN";   
            end

            logMessage = "";
            trained = false;

            % Se o checkbox 'Start' estiver ativo
            if app.StartCheckBox.Value == 1
                load(strcat('testData\binaryImages', 'start'), 'binaries');
                load(strcat('testData\target', 'start'), 'target');
                [msg, trained] = trainNN(app, binaries, target);
                logMessage = append(logMessage, msg);
            end

            % Se o checkbox 'Train' estiver ativo
            if app.TrainCheckBox.Value == 1
                load(strcat('testData\binaryImages', 'train'), 'binaries');
                load(strcat('testData\target', 'train'), 'target');
                [msg, trained] = trainNN(app, binaries, target);
                logMessage = append(logMessage, msg);
            end

            % Se o checkbox 'Test' estiver ativo
            if app.TestCheckBox.Value == 1
                load(strcat('testData\binaryImages', 'test'), 'binaries');
                load(strcat('testData\target', 'test'), 'target');
                [msg, trained] = trainNN(app, binaries, target);
                logMessage = append(logMessage, msg);
            end

            % Mostra mensagem final no painel de logs
            if trained
                app.logTextArea.Value = append("Rede neural treinada com sucesso!", logMessage);
            else
                app.logTextArea.Value = sprintf("Não foi possivel treinar a rede!");
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            % % % % QUANDO CLICA NO BOTÃO "TEST" % % % % 
            % Valida se algum dataset foi selecionado
            if app.TestCheckBox == 0 && app.TrainCheckBox == 0 && app.StartCheckBox == 0
                app.logTextArea.Value = sprintf("%s\nPlease choose one file to test the NN!\n", app.logTextArea.Value);
             end

             logMessage = "";

             % Verifica se existe uma rede treinada
             if isempty(app.bestNet)
                 app.logTextArea.Value = sprintf("%s\nYou need one trained NN to test!\n", app.logTextArea.Value);
                 return;
             end

             addpath('helper\');

              % Testa com o conjunto 'start'
             if app.StartCheckBox == 1
                binaries = load(strcat('testData\binaryImages', 'start'), 'binaries');
                target = load(strcat('testData\target', 'start'), 'target');
                [testAccuracy, ~] = testNeuralNetworks(app.bestNet.net, binaries, target);
                logMessage = sprintf(' Precisão Teste: %.2f%%\n', testAccuracy);
             end

             % Testa com 'train'
             if app.TrainCheckBox == 1
                binaries = load(strcat('testData\binaryImages', 'train'), 'binaries');
                target = load(strcat('testData\target', 'train'), 'target');
                [testAccuracy, ~] = testNeuralNetworks(app.bestNet.net, binaries, target);
                logMessage = sprintf(' Precisão Teste: %.2f%%\n', testAccuracy);
             end

             % Testa com 'test'
             if app.TestCheckBox == 1
                binaries = load(strcat('testData\binaryImages', 'test'), 'binaries');
                target = load(strcat('testData\target', 'test'), 'target');
                [testAccuracy, ~] = testNeuralNetworks(app.bestNet.net, binaries, target);
                logMessage = sprintf(' Precisão Teste: %.2f%%\n', testAccuracy);
             end

             % Mostra o resultado no log
             app.logTextArea.Value = sprintf("Rede neural testada com sucesso!\n%s", logMessage);
        end

        % Cell edit callback: transferFunctionTable
        function transferFunctionTableCellEdit(app, event)
            % % % % QUANDO O UTILIZADOR EDITA A TABELA DE FUNÇÕES DE ATIVAÇÃO  % % % % 
            indices = event.Indices;     % [linha, coluna] editada
            newData = event.NewData;     % Novo valor inserido

            idx = 0;

            % Coluna 1 = função de ativação
            if indices(2) == 1
                for i = 1:numel(app.activationFcnNames)
                    if newData == string(app.activationFcnNames(i))
                        app.isValid = true;
                        idx = i;
                        break;
                    end
                end

                if app.isValid == true
                    app.activationFcn(indices(1)) = app.activationFcnNames(idx);
                else
                    errordlg("Please enter a valid transfer function", "Incorrect parameters");
                end

            % Coluna 2 = número de neurónios
            elseif indices(2) == 2
                if ~isnumeric(newData) || newData < 0 
                    errordlg("Please enter a valid transfer function", "Incorrect parameters");
                elseif newData == 0
                    % ignora se for zero
                else
                    app.topology(indices(1)) = newData;
                end
            end   

            updateFields(app);
            app.isValid = false;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Botão "Add": adiciona uma nova camada escondida
            transferFcnIndex = app.ActivationFunctionDropDown.ValueIndex;
            neurons = app.NeuronsEditField.Value;

            % Limita a um máximo de 7 camadas
            if numel(app.topology) >= 7
                return;
            end

            % Adiciona a função de ativação e o nº de neurónios à topologia atual
            app.activationFcn = [app.activationFcn app.activationFcnNames(transferFcnIndex)];
            app.topology = [app.topology neurons];

            updateFields(app); % Atualiza a tabela e os campos visuais
        end

        % Button pushed function: RemoveoneButton
        function RemoveoneButtonPushed(app, event)
            % Botão "Remove one": remove a última camada escondida
            if numel(app.topology) > 1
                app.activationFcn(end) = [];
                app.topology(end) = [];
            end
            updateFields(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            % Botão "Clear": repõe a topologia para o valor inicial
            app.activationFcn = [app.activationFcnNames(13)];
            app.topology = [10];            
            updateFields(app);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Botão "Import": importa rede a partir de ficheiro .mat
            [file, location] = uigetfile('saved_nets\*.mat');
            if isequal(file, 0)
                app.logTextArea.Value = "Error getting file";
            end

            net = load(fullfile(location, file), 'net');
            app.bestNet = net.net;
            app.noteLabel.Visible = true;

            % sets fields information
            % remove 1 for the output layer
            % Reconstrói topologia e funções a partir da rede importada
            dimension = app.bestNet.net.numLayers - 1; 
            app.topology = [];

            if dimension == 1
                neurons = app.bestNet.net.layers{end - 1}.dimensions;
                app.topology = [neurons];
                trFcn = app.bestNet.net.layers{1}.transferFcn;

                for i = 1:numel(app.activationFcnNames)
                    if strcmp(trFcn, string(app.activationFcnNames(i)))
                        app.activationFcn = [app.activationFcnNames(i)];
                        break;
                    end
                end
            else
                for i = 1:dimension
                    app.topology = [app.topology app.bestNet.net.layers{i}.dimension];
                    for i = 1:numel(app.activationFcnNames)
                        if strcmp(app.bestNet.net.layers{i}.transferFcn, string(app.activationFcnNames(i)))
                            app.activationFcn = [app.activationFcn app.activationFcnNames(i)];
                            break;
                        end
                    end
                        
                end    
            end

            % Atualiza os parâmetros restantes
            app.outputFcn = app.bestNet.net.layers{end}.transferFcn;

            app.trainRatio = app.bestNet.tr.divideParam.trainRatio * 100;
            app.valRatio = app.bestNet.tr.divideParam.valRatio * 100;
            app.testRatio = app.bestNet.tr.divideParam.testRatio * 100;
            
            app.trainFcn = app.bestNet.net.trainFcn;
            app.divideFcn = app.bestNet.net.divideFcn;

            app.DoyouhavealoadednetLamp.Color = 'g';
            app.noteLabel.Visible = true;
            app.ClearNN.Visible = true;

            app.logTextArea.Value = "Rede importada com sucesso!";

            updateFields(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Botão "Export": exporta a rede treinada atual para ficheiro .mat
            net = app.bestNet;
            uisave({'net'}, 'saved_nets\best_net.mat');
        end

        % Value changed function: TestRatioEditField
        function TestRatioEditFieldValueChanged(app, event)
            % Edição do campo Test Ratio
            value = app.TestRatioEditField.Value;
            if value == app.testRatio
                return;
            end

            testRt = (app.testRatio - value) / 2;
            app.testRatio = value;
            if app.testRatio > value
                app.valRatio = app.valRatio - testRt;
                app.trainRatio = app.trainRatio - testRt;
            else
                app.valRatio = app.valRatio + testRt;
                app.trainRatio = app.trainRatio + testRt;
            end

            updateFields(app);
        end

        % Value changed function: TrainRatioEditField
        function TrainRatioEditFieldValueChanged(app, event)
            % Edição do campo Train Ratio
            value = app.TrainRatioEditField.Value;
            if value == app.trainRatio
                return;
            end
            
            if value == 100
                % Rede é treinada com todos os dados
                app.divideFcn = 'dividetrain';
                app.testRatio = 0;
                app.valRatio = 0;
            else
                trainRt = (app.trainRatio - value) / 2;
                app.trainRatio = value;

                if app.trainRatio > value
                    app.testRatio = app.testRatio - trainRt;
                    app.valRatio = app.valRatio - trainRt;
                else
                    app.testRatio = app.testRatio + trainRt;
                    app.valRatio = app.valRatio + trainRt;
                end
            end
            updateFields(app);
        end

        % Value changed function: ValidationRatioEditField
        function ValidationRatioEditFieldValueChanged(app, event)
            % Edição do campo Validation Ratio
            value = app.ValidationRatioEditField.Value;
            if value == app.valRatio
                return;
            end

            valRt = (app.valRatio - value) / 2;
            app.valRatio = value;
            
            if app.valRatio > value
                app.trainRatio = app.trainRatio - valRt;
                app.testRatio = app.testRatio - valRt;
            else
                app.trainRatio = app.trainRatio + valRt;
                app.testRatio = app.testRatio + valRt;
            end
            updateFields(app);
        end

        % Value changed function: OutputFunctionDropDown
        function OutputFunctionDropDownValueChanged(app, event)
            app.outputFcn = app.OutputFunctionDropDown.Value;
            updateFields(app);
        end

        % Button pushed function: ClearNN
        function ClearNNPushed(app, event)
            app.bestNet = struct('net', {}, 'tr', {});
            app.DoyouhavealoadednetLamp.Color = 'r';
            app.noteLabel.Visible = false;
            app.ClearNN.Visible = false;
            updateFields(app);
        end

        % Value changed function: NumberEpochsEditField
        function NumberEpochsEditFieldValueChanged(app, event)
            app.epochs = app.NumberEpochsEditField.Value;
            updateFields(app);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            imageSize = [25 25];
            
            % axis(app.UIAxes,'off');        
        
            [imgName, folderPath] = uigetfile('desenhadas\*.png', 'Seleciona uma imagem PNG');
            if isequal(imgName, 0)
                app.logTextArea.Value = 'Seleção de imagem cancelada';
                return;
            end
            currentImage = imread(fullfile(folderPath, imgName));
            imshow(currentImage, 'Parent', app.UIAxes);
            currentImage = rgb2gray(currentImage);
            currentImage = imresize(currentImage, imageSize);
            app.binaryImage = imbinarize(currentImage);

            app.binaryImage = app.binaryImage(:);

            app.logTextArea.Value = 'Imagem Carregada';
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
    
        if isempty(app.binaryImage)
            app.logTextArea.Value = 'Carregue uma imagem primeiro.';
            return;
        end
        if isempty(app.bestNet)
            app.logTextArea.Value = 'You need one trained NN to test!\n';
            return;
        end
        out = sim(app.bestNet.net, app.binaryImage);

        [~, idx] = max(out);
        app.logTextArea.Value = sprintf("A rede indica que a imagem é: %s\n", app.classes{idx});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainParams = uipanel(app.UIFigure);
            app.TrainParams.Title = 'Train Parameters';
            app.RatioSlider = uislider(app.TrainParams, 'range');
            app.RatioSlider.ValueChangingFcn = createCallbackFcn(app, @RatioSliderValueChanging, true);
            app.RatioSlider.Value = [15 60];
            app.TrainRatioEditFieldLabel = uilabel(app.TrainParams);
            app.TrainRatioEditFieldLabel.Text = 'Train Ratio';
            app.TrainRatioEditField = uieditfield(app.TrainParams, 'numeric');
            app.TrainRatioEditField.Limits = [0 100];
            app.TrainRatioEditField.RoundFractionalValues = 'on';
            app.TrainRatioEditField.ValueChangedFcn = createCallbackFcn(app, @TrainRatioEditFieldValueChanged, true);
            app.TrainRatioEditField.Editable = 'off';
            app.TrainRatioEditField.Placeholder = '70';
            app.TrainRatioEditField.Value = 70;
            app.TestRatioEditFieldLabel = uilabel(app.TrainParams);
            app.TestRatioEditFieldLabel.Text = 'Test Ratio';
            app.TestRatioEditField = uieditfield(app.TrainParams, 'numeric');
            app.TestRatioEditField.Limits = [0 100];
            app.TestRatioEditField.RoundFractionalValues = 'on';
            app.TestRatioEditField.ValueChangedFcn = createCallbackFcn(app, @TestRatioEditFieldValueChanged, true);
            app.TestRatioEditField.Editable = 'off';
            app.TestRatioEditField.Placeholder = '15';
            app.TestRatioEditField.Value = 15;
            app.ValidationRatioEditFieldLabel = uilabel(app.TrainParams);
            app.ValidationRatioEditFieldLabel.Text = ' Validation Ratio';
            app.ValidationRatioEditField = uieditfield(app.TrainParams, 'numeric');
            app.ValidationRatioEditField.Limits = [0 100];
            app.ValidationRatioEditField.ValueChangedFcn = createCallbackFcn(app, @ValidationRatioEditFieldValueChanged, true);
            app.ValidationRatioEditField.Editable = 'off';
            app.ValidationRatioEditField.Placeholder = '15';
            app.ValidationRatioEditField.Value = 15;
            app.DivisionFunctionsDropDownLabel = uilabel(app.TrainParams);
            app.DivisionFunctionsDropDownLabel.Text = 'Division Functions';
            app.DivisionFunctionsDropDown = uidropdown(app.TrainParams);
            app.DivisionFunctionsDropDown.Items = {'dividerand', 'divideint', 'dividetrain', 'divideind', 'divideblock'};
            app.DivisionFunctionsDropDown.ValueChangedFcn = createCallbackFcn(app, @DivisionFunctionsDropDownValueChanged, true);
            app.DivisionFunctionsDropDown.Placeholder = 'dividerand';
            app.DivisionFunctionsDropDown.Value = 'dividerand';
            app.TrainFunctionDropDownLabel = uilabel(app.TrainParams);
            app.TrainFunctionDropDownLabel.Text = 'Train Function';
            app.TrainFunctionDropDown = uidropdown(app.TrainParams);
            app.TrainFunctionDropDown.Items = {'trainbfg', 'trainbfgc', 'trainbr', 'trainbu', 'trainc', 'traincgb', 'traincgf', 'traincgp', 'traingd', 'traingda', 'traingdm', 'traingdx', 'trainlm', 'trainoss', 'trainr', 'trainrp', 'trainru', 'trains', 'trainscg'};
            app.TrainFunctionDropDown.Placeholder = 'trainlm';
            app.TrainFunctionDropDown.Value = 'trainlm';
            app.TestButton = uibutton(app.TrainParams, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TrainButton = uibutton(app.TrainParams, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_2);
            app.transferFunctionTable = uitable(app.Panel_2);
            app.transferFunctionTable.ColumnName = {'Transfer Function'; 'Neurons'};
            app.transferFunctionTable.RowName = {};
            app.transferFunctionTable.CellEditCallback = createCallbackFcn(app, @transferFunctionTableCellEdit, true);
            app.LogsEditFieldLabel = uilabel(app.Panel_2);
            app.LogsEditFieldLabel.Text = 'Logs';
            app.logTextArea = uieditfield(app.Panel_2, 'text');
            app.logTextArea.Editable = 'off';
            app.LoadImageButton = uibutton(app.Panel_2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ClassifyButton = uibutton(app.Panel_2, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.FilesPanel = uipanel(app.UIFigure);
            app.FilesPanel.Title = 'Files';
            app.StartCheckBox = uicheckbox(app.FilesPanel);
            app.StartCheckBox.Text = 'Start';
            app.StartCheckBox.Value = true;
            app.TrainCheckBox = uicheckbox(app.FilesPanel);
            app.TrainCheckBox.Text = 'Train';
            app.TestCheckBox = uicheckbox(app.FilesPanel);
            app.TestCheckBox.Text = 'Test';
            app.ImportButton = uibutton(app.FilesPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.FilesPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.CreatetheNeuralNetworkPanel = uipanel(app.UIFigure);
            app.CreatetheNeuralNetworkPanel.Title = 'Create the Neural Network';
            app.AddButton = uibutton(app.CreatetheNeuralNetworkPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ActivationFunctionDropDownLabel = uilabel(app.CreatetheNeuralNetworkPanel);
            app.ActivationFunctionDropDownLabel.Text = 'Activation Function';
            app.ActivationFunctionDropDown = uidropdown(app.CreatetheNeuralNetworkPanel);
            app.ActivationFunctionDropDown.Items = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            app.ActivationFunctionDropDown.Value = 'tansig';
            app.OutputFunctionDropDownLabel = uilabel(app.CreatetheNeuralNetworkPanel);
            app.OutputFunctionDropDownLabel.Text = 'Output Function';
            app.OutputFunctionDropDown = uidropdown(app.CreatetheNeuralNetworkPanel);
            app.OutputFunctionDropDown.Items = {'compet', 'hardlim', 'hardlims', 'logsig', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'softmax', 'tansig', 'tribas'};
            app.OutputFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputFunctionDropDownValueChanged, true);
            app.OutputFunctionDropDown.Value = 'purelin';
            app.NeuronsEditFieldLabel = uilabel(app.CreatetheNeuralNetworkPanel);
            app.NeuronsEditFieldLabel.Text = 'Neurons';
            app.NeuronsEditField = uieditfield(app.CreatetheNeuralNetworkPanel, 'numeric');
            app.NeuronsEditField.Limits = [1 100];
            app.NeuronsEditField.RoundFractionalValues = 'on';
            app.NeuronsEditField.ValueDisplayFormat = '%.0f';
            app.NeuronsEditField.Placeholder = '10';
            app.NeuronsEditField.Value = 10;
            app.NumberEpochsEditFieldLabel = uilabel(app.CreatetheNeuralNetworkPanel);
            app.NumberEpochsEditFieldLabel.Text = 'Number Epochs';
            app.NumberEpochsEditField = uieditfield(app.CreatetheNeuralNetworkPanel, 'numeric');
            app.NumberEpochsEditField.Limits = [1 1000];
            app.NumberEpochsEditField.RoundFractionalValues = 'on';
            app.NumberEpochsEditField.ValueDisplayFormat = '%.0f';
            app.NumberEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberEpochsEditFieldValueChanged, true);
            app.NumberEpochsEditField.Placeholder = '100';
            app.NumberEpochsEditField.Value = 100;
            app.RemoveoneButton = uibutton(app.CreatetheNeuralNetworkPanel, 'push');
            app.RemoveoneButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveoneButtonPushed, true);
            app.RemoveoneButton.Text = 'Remove one';
            app.ClearButton = uibutton(app.CreatetheNeuralNetworkPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CurrentnetworkPanel = uipanel(app.UIFigure);
            app.CurrentnetworkPanel.Title = 'Current network';
            app.DoyouhavealoadednetLampLabel = uilabel(app.CurrentnetworkPanel);
            app.DoyouhavealoadednetLampLabel.Text = 'Do you have a loaded net?';
            app.DoyouhavealoadednetLamp = uilamp(app.CurrentnetworkPanel);
            app.noteLabel = uilabel(app.CurrentnetworkPanel);
            app.noteLabel.Visible = 'off';
            app.noteLabel.Text = 'Changing parameters on the menus will not change the current Net';
            app.ClearNN = uibutton(app.CurrentnetworkPanel, 'push');
            app.ClearNN.ButtonPushedFcn = createCallbackFcn(app, @ClearNNPushed, true);
            app.ClearNN.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end